classdef videostereoscopic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LoadVideoButton   matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        VidAxes           matlab.ui.control.UIAxes
        FrameSliderLabel  matlab.ui.control.Label
        FrameSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Vid             %Video Reader
        FrameNo         %Determines frame number
        WVid            %Video Writer
    end
   properties (Access = public)
       Frame           %Frame Handler for VidAxes
       Extracted       %Haar Cascade Performed on Frame
   end
    methods (Access = public)
             
        function VideoSelect(app)
            filter = {'*.avi; *.mp4','All Video Files'};
            [f,p] = uigetfile(filter);
            
            if (ischar(p))
                    fname = [p,f];
                    
                    while 1
                        b = VideoLoad(app,fname);
                        if(b == 0)
                            break;
                        end
                    end
            end
        end
        
        function result = VideoLoad(app, videofile)
            try
                app.Vid = VideoReader(videofile);
                app.FrameNo = 1;
                VideoRefresh(app,app.FrameNo);
                result = 0;  
                app.FrameSlider.Limits = [1, app.Vid.NumberOfFrames];
            catch ME
                uialert(app.UIFigure, ME.message, 'Video Error');
                result = -1;
                return;
            end
        end
        
        function VideoRefresh(app,value)
            app.Frame = read(app.Vid,value);
            imshow(app.Frame,'Parent',app.VidAxes);
            drawnow;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            VideoSelect(app);
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
        
            %Display File dialog
            filter = {'*.avi','*.mp4','All Video Files'};
            [f,p] = uigetfile(filter);
            
            if (ischar(p))
                    fname = [p,f];
                    videoSelect(app,fname);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.WVid = VideoWriter('cascaded.avi');
            open(app.WVid);
            
            app.FrameNo = 1;
            VideoRefresh(app,app.FrameNo);
            
            while (app.FrameNo ~= app.Vid.NumberOfFrames)
                
                app.Extracted = extractEyesOCV(app.Frame);
                app.FrameSlider.Value = app.FrameNo;
                app.FrameNo = app.FrameNo + 1;
                imshow(app.Extracted,'Parent',app.VidAxes);
                drawnow;
                writeVideo(app.WVid,app.Extracted);
                app.Frame = read(app.Vid,app.FrameNo);
                
            end
            close(v)
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameNo = round(app.FrameSlider.Value);
            VideoRefresh(app,app.FrameNo);  
        end

        % Callback function
        function FrameSliderValueChanging(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.VidAxes = uiaxes(app.UIFigure);
            title(app.VidAxes, 'Preview')
            xlabel(app.VidAxes, '')
            ylabel(app.VidAxes, '')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = videostereoscopic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end