classdef stereoscopic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CamL % LeftCam
        CamR % RightCam
        HimgL %Image Handler 1
        HimgR %Image Handler 2
        SnapTimer %Snapshot Timer
    end

    
    methods (Access = public)

    end
    
    methods (Access = private)
        
        function SnapshotTimer(app)
            app.HimgL = image(app.UIAxes,snapshot(app.CamL),'uint8');
            app.HimgR = image(app.UIAxes2,snapshot(app.CamR),'uint8');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CameraStart)
            app.CamL = webcam(1);
            app.CamR = webcam(2);
            %app.CamL.Resolution = '1920x1080';
            %app.CamR.Resolution = '1920x1080';
            app.CamR.Zoom = 100;
            app.CamL.Zoom = 100;
            app.CamR.Tilt = 0;
            app.CamL.Tilt = 0;
            app.CamR.WhiteBalance = 3000;
            app.CamL.WhiteBalance = 3000;
            app.HimgL = image(app.UIAxes,zeros(size(snapshot(app.CamL)),'uint8'));
            app.HimgR = image(app.UIAxes2,zeros(size(snapshot(app.CamR)),'uint8'));
            preview(app.CamL,app.HimgL);
            preview(app.CamL,app.HimgL);
%           app.SnapTimer = timer ( ...
%                                    'BusyMode','queue', ...
%                                    'ExecutionMode','fixedRate', ...
%                                    'Period', 0.1, ...
%                                    'TimerFcn', @app.SnapshotTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stereoscopic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end