classdef stereoscopic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        VideoSelectionTab           matlab.ui.container.Tab
        SelectavideotoperformGazeEstimationonLabel  matlab.ui.control.Label
        SelectavideoforrelevantStereoscopicCalibrationsegmentationLabel  matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        EditField_2                 matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        BrowseButton_2              matlab.ui.control.Button
        CalibrationVideoSegmentationTab  matlab.ui.container.Tab
        StartFrameDissectionButton  matlab.ui.control.Button
        VideoLabel                  matlab.ui.control.Label
        CVDFname                    matlab.ui.control.Label
        FrameSkipLabel              matlab.ui.control.Label
        FrameSkipSpinner            matlab.ui.control.Spinner
        SaveinEditFieldLabel        matlab.ui.control.Label
        SaveinEditField             matlab.ui.control.EditField
        BrowseButton_3              matlab.ui.control.Button
        FrameNumberLabel            matlab.ui.control.Label
        FrameNumberGauge            matlab.ui.control.LinearGauge
        DissectionLamp              matlab.ui.control.Lamp
        FinLabel                    matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        HaarCascadeROITab           matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        SelectaframewhereboundingboxesareclearlypresentforalleyesLabel  matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        SaveBoundsButton            matlab.ui.control.Button
        FrameNoSpinnerLabel         matlab.ui.control.Label
        FrameNoSpinner              matlab.ui.control.Spinner
        FrameNumberGauge_2          matlab.ui.control.LinearGauge
        VideoLabel_2                matlab.ui.control.Label
        HaarV                       matlab.ui.control.Label
        LoadVideoButton             matlab.ui.control.Button
        NextFrameButton             matlab.ui.control.Button
        BoundSavedLabel             matlab.ui.control.Label
        VideoLoadedintoMemoryLabel  matlab.ui.control.Label
        ThinkingLabel               matlab.ui.control.Label
        CentreGazeCoordinatesTab    matlab.ui.container.Tab
        FrameNumberGauge_3          matlab.ui.control.LinearGauge
        CentreGazeCalibrationLabel  matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        LoadVideoButton_2           matlab.ui.control.Button
        HaarV_2                     matlab.ui.control.Label
        FrameNoSpinner_2Label       matlab.ui.control.Label
        FrameNoSpinner_2            matlab.ui.control.Spinner
        UIAxes2                     matlab.ui.control.UIAxes
        CheckFrameButton            matlab.ui.control.Button
        VideoLoadedCheckaframethensaveitLabel  matlab.ui.control.Label
        SaveCoordinatesButton       matlab.ui.control.Button
        CoordinatessavedLabel       matlab.ui.control.Label
        ProcessLabel                matlab.ui.control.Label
        GazeEstimationTab           matlab.ui.container.Tab
        ChecklistbeforeGazeEstimationLabel  matlab.ui.control.Label
        LampVid                     matlab.ui.control.Lamp
        LampROI                     matlab.ui.control.Lamp
        LampCentre                  matlab.ui.control.Lamp
        VideoSelectedLabel          matlab.ui.control.Label
        HaarROIBoundsFoundLabel     matlab.ui.control.Label
        CentreGazeCoordinatesFoundLabel  matlab.ui.control.Label
        Gauge                       matlab.ui.control.LinearGauge
        LoadVideoButton_3           matlab.ui.control.Button
        Label_5                     matlab.ui.control.Label
        StartGazeEstimationButton   matlab.ui.control.Button
        VideoSaveLocationLabel      matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        BrowseButton_4              matlab.ui.control.Button
        Processing                  matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        CalibVid        %Calibration Video
        CalibVidFname   %Calibration Video File Name
        
        TestVid         %Video to perform Gaze Estimation
        TestVidFname    %Test Video File Name
        
        CalibPicDir     %Directory of calibration photos
        Skip            %Number of frames to skip with dissection
        
        HaarExtract     %Extracted Haar Image
        HaarBounds      %TheROI
        HaarBoundsSaved %Saved Bounds
        
        O_x             % Origin X for Ground Truth
        O_y             % Origin Y for Ground Truth
        
        Ox_save         % Origin X Saved
        Oy_save         % Origin Y Saved
        
        VidLock         %Ensure Video is locked
        BoundLock       %Ensure Bound is found
        OriginLock      %Ensures Origin is set
        
        saveDir         %Save Directory for Video Files
        
        saveVid1        %Save the Camera Feed
        saveVid2        %Save the Coordinates Feed
        saveVidFname    %File Name for Final Video 
        saveVidFname2   %File Name for Coordinates Video
        saveCOFname     %File Name for Coordinates in CSV
        saveCSV         %Coordinates to save
        
        
    end
    
    methods (Access = private)
               
        function CycleValidHaar(app)
            %Cycles through Valid Haar Cascades of two eyes.
            app.ThinkingLabel.Visible = 1;
            drawnow;
            valid = 0;
            Hframe = read(app.TestVid,app.FrameNumberGauge_2.Value);
            [app.HaarBounds, app.HaarExtract] = extractEyesOCVPoints(Hframe);
            
            while valid < 1            
                
                [app.HaarBounds, app.HaarExtract] = extractEyesOCVPoints(Hframe);
                
                for i = 1:4
                    if app.HaarBounds(:,i) ~= [0;0;0;0]
                        valid = valid + 0.25;
                    end
                end
                
                if valid < 1
                    
                    if (app.FrameNumberGauge_2.Value >= app.TestVid.NumberOfFrames)
                        app.FrameNumberGauge_2.Value = 1;
                        app.FrameNoSpinner.Value = app.FrameNumberGauge_2.Value;
                    
                    else
                        app.FrameNumberGauge_2.Value = app.FrameNumberGauge_2.Value + 1;
                        app.FrameNoSpinner.Value = app.FrameNumberGauge_2.Value;
                    end
                    
                    valid = 0;
                    Hframe = read(app.TestVid,app.FrameNumberGauge_2.Value);
                    
                end                            
            end
            
            imshow(app.HaarExtract,'Parent',app.UIAxes);
            app.ThinkingLabel.Visible = 0;
            drawnow;
            
        end
        
        function Daugman(app)
            
            % Performs Daugman's algo for truth      
            Tframe = read(app.TestVid,app.FrameNoSpinner_2.Value);
            box_x = app.HaarBoundsSaved(1,:);
            box_y = app.HaarBoundsSaved(2,:);
            width = app.HaarBoundsSaved(3,:);
            height = app.HaarBoundsSaved(4,:);
            
            % Ground Truth Derivation
            app.O_x = zeros(4,1);       %X Value 
            app.O_y = zeros(4,1);       %Y Value 
            r_iris_cal = zeros(4,1);    %Radius Value 
            
            
            parfor (i = 1:4)
                I_new_crop = imcrop(Tframe,[box_x(i),box_y(i),width(i),height(i)]);
                I_cal_bw = rgb2gray(I_new_crop);
                
                %testing gaussian filter
                I_cal_bw = imgaussfilt(I_cal_bw,0.5);

                [ciris,~,~] = thresh(I_cal_bw, 20, 25);
                r_iris_cal(i) = ciris(3);

                
                Ox(i) = ciris(2)+box_x(i);
                Oy(i) = ciris(1)+box_y(i);
                
            end
            
            app.O_x  = Ox;
            app.O_y = Oy;
            
            for i = 1:4
                Tframe = insertShape(Tframe,"Circle",[app.O_x(i),app.O_y(i) r_iris_cal(i)],"LineWidth", 3,"Color","yellow");
            end
            
            imshow(Tframe,'Parent',app.UIAxes2); 
        end
        
        function Daugman2(app)
            
            app.saveVid1 = VideoWriter(app.saveVidFname);
            %app.SaveVid2 = VideoWriter(app.saveVidFname2);
            
            app.saveVid1.FrameRate = 30;
            %app.SaveVid2.FrameRate = 30;
            
            open(app.saveVid1);
            %open(app.SaveVid2);
            
            %Create the CSV matrix
            app.saveCSV = zeros(2,app.TestVid.NumberOfFrames);
            
            orx = app.Ox_save;          %Load Origin X values
            ory = app.Oy_save;          %Load Origin Y values
            
            box_x = app.HaarBoundsSaved(1,:);
            box_y = app.HaarBoundsSaved(2,:);
            width = app.HaarBoundsSaved(3,:);
            height = app.HaarBoundsSaved(4,:);
            
            xnew = zeros(4,1);          %X Value 
            ynew = zeros(4,1);          %Y Value
            
            rad = zeros(4,1);           %Radius Value 
            x_screen = zeros(4,1);      %Calculated Gaze x
            y_screen = zeros(4,1);      %Calculated Gaze y
            
            r = 23;                     %radius of eyeball
            d = 400;                    %Distance between subject and plane.
            
            diff_x = zeros(4,1);
            diff_y = zeros(4,1);
            
            beta = zeros(4,1);
            alpha = zeros(4,1);
            
            for j = 1:app.TestVid.NumberOfFrames
                
                app.Gauge.Value = j;
                drawnow;
                Tframe = read(app.TestVid, j);
            
                parfor (z = 1:4)
                    I_new_crop = imcrop(Tframe,[box_x(z),box_y(z),width(z),height(z)]);
                    I_cal_bw = rgb2gray(I_new_crop);
                    I_cal_bw = imgaussfilt(I_cal_bw,0.5);
    
                    [ciris,~,~] = thresh(I_cal_bw, 20, 25);
                    rad(z) = ciris(3);
    
                    
                    xnew(z) = ciris(2)+box_x(z);
                    ynew(z) = ciris(1)+box_y(z);
                    
                    
                    %[x_screen(z),y_screen(z)] = gaze_estimation(app,orx(z),ory(z),xnew(z),ynew(z),r,d);
                    diff_x(z) = 0.294*(xnew(z) - orx(z)); %convert pixel to mm (0.294 scale)
                    diff_y(z) = 0.294*(ynew(z) - ory(z));  

                    beta(z) = asin(diff_y(z)./r);          
                    alpha(z) = asin(diff_x(z)./(r.*cos(beta(z))));
        
       
                    x_screen(z) = (d+r).*tan(alpha(z));
                    y_screen(z) = (d+r).*sec(alpha(z)).*tan(beta(z));
                    
                end
                
                for k = 1:4
                    Tframe = insertShape(Tframe,"Circle",[xnew(k),ynew(k) rad(k)],"LineWidth", 3,"Color","yellow");
                end
                
                app.saveCSV(1,j) = mean(x_screen);
                app.saveCSV(2,j) = mean(y_screen);
                writeVideo(app.saveVid1,Tframe);
                writematrix(app.saveCSV,app.saveCOFname);
            end
            
            
            close(app.saveVid1);
            
        end
        
        function [x, y] = gaze_estimation(app, Ox, Oy, eye_x, eye_y,r,d) 
        %difference between origin and new iris centre
         diff_x = 0.294*(eye_x - Ox); %convert pixel to mm (0.294 scale)
         diff_y = 0.294*(eye_y - Oy); %convert pixel to mm
         
        %angle calculations
         beta = asin(diff_y./r);
         alpha = asin(diff_x./(r.*cos(beta)));
        
        %estimated point on screen
         x = (d+r).*tan(alpha);
         y = (d+r).*sec(alpha).*tan(beta);
        end
    end
    
    
    methods (Access = public)
        function frameDissection(app,path)
                   
            % Check and make folder
            try
                
                if isempty(regexp(path, '^[a-zA-Z]:\\(((?![<>:"/\\|?*]).)+((?<![ .])\\)?)*$', 'once'))
                    uialert(app.UIFigure,'Invalid directory; Please check.','Invalid directory')
                    return;
                end    
            
                if ~isfolder(path)
                    mkdir(path);
                end
            
            catch ME2
                uialert(app.UIFigure,ME2.message,"Folder selection is invalid")
                return;
            end
            
            % Make two folders to represent each cam
            c1path = [path '\c1'];
            c2path = [path '\c2'];
            
            if isfolder(c2path) || isfolder(c1path)
                
                
                answer = questdlg(['Camera folders have been previously made. ' ...
                    'This will overwrite any images currently inside the folder. Are you sure you want to continue?'], ...
                    'Warning');
                
                switch answer
                    case 'No'
                       return;
                    case ''
                        return;
                    case 'Cancel'
                        return;
                    
                end
            end
            
            mkdir(c1path);
            mkdir(c2path);
            
            %Try to load the video
            try
                app.CalibVid = VideoReader(app.CalibVidFname);
                app.FrameNumberGauge.Value = 1;
                app.FrameNumberGauge.Limits = [1,app.CalibVid.NumberOfFrames()];
            catch ME
                uialert(app.UIFigure, ME.message, "Invalid Calibration Video")
                return;
            end
            
            
            for iFrame = 1:app.FrameSkipSpinner.Value:app.CalibVid.NumberOfFrames()
                app.FrameNumberGauge.Value = iFrame;
                framet = read(app.CalibVid,iFrame);
                imwrite(framet(:,1:1920,:), fullfile(c1path, sprintf('c1_%06d.png', iFrame)));
                imwrite(framet(:,1921:3840,:), fullfile(c2path, sprintf('c2_%06d.png', iFrame)));
            end
            
            app.FrameNumberGauge = 1;
            
        end
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CameraStart)


        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            filter = {'*.avi;*.mp4','All Video Files'};
            [f,p] = uigetfile(filter);
            if (ischar(p))
                app.EditField.Value = [p,f];
                app.TestVidFname = [p,f];
                app.HaarV.Text = [p,f];
                app.HaarV_2.Text = [p,f];
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.TestVidFname = app.EditField.Value;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            filter = {'*.avi;*.mp4','All Video Files'};
            [f,p] = uigetfile(filter);
            if (ischar(p))
                app.EditField_2.Value = [p,f];
                app.CalibVidFname = [p,f];
                app.CVDFname.Text = [p,f];
                drawnow;
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.CalibVidFname = app.EditField_2.Value;
            app.CVDFname.Text = app.CalibVidFname;
            drawnow
        end

        % Callback function
        function CalibrationVideoDissectionTabSizeChanged(app, event)
            
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            path = uigetdir;
            if (ischar(path))
                app.SaveinEditField.Value = path;
                app.CalibPicDir = path;
            end
        end

        % Value changed function: SaveinEditField
        function SaveinEditFieldValueChanged(app, event)
            %In Calib: Select image save Directory
            app.CalibPicDir = app.SaveinEditField.Value;            
        end

        % Button pushed function: StartFrameDissectionButton
        function StartFrameDissectionButtonPushed(app, event)
            %Stereo Calib: Starts frame dissection when button is pressed
            app.DissectionLamp.Color = [0;1;0];
            app.FinLabel.Visible = 0;
            frameDissection(app,app.CalibPicDir)
            app.DissectionLamp.Color = [1;0;0];
            app.FinLabel.Visible = 1;
        end

        % Value changed function: FrameSkipSpinner
        function FrameSkipSpinnerValueChanged(app, event)
                 
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            %Haar: load video to memory
            app.BoundSavedLabel.Visible = 0;
            try
                app.TestVid = VideoReader(app.TestVidFname);
                app.FrameNumberGauge_2.Value = 1;
                app.FrameNumberGauge_2.Limits = [1,app.TestVid.NumberOfFrames()];
                app.FrameNoSpinner.Value = 1;
                app.FrameNoSpinner.Limits = [1,app.TestVid.NumberOfFrames()];
            catch ME
                uialert(app.UIFigure, ME.message, "Invalid Test Video")
                return;
            end
            app.VideoLoadedintoMemoryLabel.Visible = 1;
            app.CycleValidHaar();
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
           if app.FrameNumberGauge_2.Value >= app.TestVid.NumberOfFrames()
               app.FrameNumberGauge_2.Value = 1;
           end
           app.FrameNumberGauge_2.Value = app.FrameNumberGauge_2.Value+1;
           app.FrameNoSpinner.Value = app.FrameNumberGauge_2.Value;
           app.CycleValidHaar();
        end

        % Button pushed function: SaveBoundsButton
        function SaveBoundsButtonPushed(app, event)
            if isempty(app.HaarBounds)
                uialert(app.UIFigure,'No Bounds have been calculated, load the video first.',"No Bounds Selected");
                return;
            end
            app.HaarBoundsSaved = app.HaarBounds;
            app.BoundSavedLabel.Visible = 1;
            app.BoundLock = 1;
            app.LampROI.Color = [0,1,0];
        end

        % Button pushed function: LoadVideoButton_2
        function LoadVideoButton_2Pushed(app, event)
            if isempty(app.HaarBoundsSaved)
                uialert(app.UIFigure,'No Bounds have been selected; Go to the previous tab to set them.',"No Bounds Selected");
                return;
            end
            
            try
                app.TestVid = VideoReader(app.TestVidFname);
                app.FrameNumberGauge_3.Value = 1;
                app.FrameNumberGauge_3.Limits = [1,app.TestVid.NumberOfFrames()];
                app.FrameNoSpinner_2.Value = 1;
                app.FrameNoSpinner_2.Limits = [1,app.TestVid.NumberOfFrames()];
            catch ME
                uialert(app.UIFigure, ME.message, "Invalid Test Video");
                return;
            end
            app.VideoLoadedCheckaframethensaveitLabel.Visible = 1;
        end

        % Value changed function: FrameNoSpinner_2
        function FrameNoSpinner_2ValueChanged(app, event)
            if isempty(app.HaarBoundsSaved)
                uialert(app.UIFigure,'No Bounds have been selected; Go to the previous tab to set them.',"No Bounds Selected");
                app.FrameNoSpinner_2.Value = 1;
                return;
            end
            app.FrameNumberGauge_3.Value = app.FrameNoSpinner_2.Value;
        end

        % Value changed function: FrameNoSpinner
        function FrameNoSpinnerValueChanged(app, event)
            app.FrameNumberGauge_2.Value = app.FrameNoSpinner.Value;
            app.CycleValidHaar();  
            
        end

        % Button pushed function: CheckFrameButton
        function CheckFrameButtonPushed(app, event)
            app.ProcessLabel.Visible = 1;
            app.Daugman();
            app.ProcessLabel.Visible = 0;
        end

        % Button pushed function: SaveCoordinatesButton
        function SaveCoordinatesButtonPushed(app, event)
            if isempty(app.O_x)
                uialert(app.UIFigure,"You haven't found the gaze at the origin; Check a frame before saving. ","Invalid Gaze Parameters");
                return;
            end
            
            app.Ox_save = app.O_x;
            app.Oy_save = app.O_y;
            
            app.CoordinatessavedLabel.Visible = 1;
            app.OriginLock = 1;
            app.LampCentre.Color = [0,1,0];
        end

        % Button pushed function: LoadVideoButton_3
        function LoadVideoButton_3Pushed(app, event)
            try
                app.TestVid = VideoReader(app.TestVidFname);
                app.LampVid.Color = [0,1,0];
                app.Gauge.Limits = [1,app.TestVid.NumberOfFrames()];
                app.Gauge.Value = 1;
            catch ME
                uialert(app.UIFigure, ME.message, "Invalid Test Video");
                return;
            end
        end

        % Button pushed function: StartGazeEstimationButton
        function StartGazeEstimationButtonPushed(app, event)
            if (app.BoundLock & app.OriginLock & app.VidLock) ~= 1
                uialert(app.UIFigure, "Cannot begin Gaze Estimation. Please fulfill the checklist.", "Checklist Incomplete");
                return;  
            end

            try
                
                if isempty(regexp(app.saveDir, '^[a-zA-Z]:\\(((?![<>:"/\\|?*]).)+((?<![ .])\\)?)*$', 'once'))
                    uialert(app.UIFigure,'Invalid directory; Please check.','Invalid directory')
                    return;
                end    
            
                if ~isfolder(app.saveDir)
                    mkdir(app.saveDir);
                end
            
            catch ME2
                uialert(app.UIFigure,ME2.message,"Folder selection is invalid")
                return;
            end
            
            
            app.saveVidFname = strcat(app.saveDir, "\camera.avi");
            %app.SaveVidFname2 = [app.saveDir "coords.avi"];
            app.saveCOFname = strcat(app.saveDir, "\coords.csv");
            
            if (isfile(app.saveVidFname) | isfile(app.saveCOFname)) == 1 %| isfile(app.SaveVidFname2)

                answer = questdlg(['Video and/or Coordinates have been detected. ' ...
                    'This will overwrite any video currently inside the folder. Are you sure you want to continue?'], ...
                    'Warning');
                
                switch answer
                    case 'No'
                       return;
                    case ''
                        return;
                    case 'Cancel'
                        return;
                    
                end
            end
            
            app.Processing.Color = [0, 1, 0];
            app.Daugman2()
            app.Processing.Color = [1, 0, 0];
            
        end

        % Button pushed function: BrowseButton_4
        function BrowseButton_4Pushed(app, event)
            path = uigetdir('C:\');
            if (ischar(path))
                app.saveDir = path;
                app.EditField_3.Value = path;
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.saveDir = app.EditField_3.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VideoSelectionTab = uitab(app.TabGroup);
            app.VideoSelectionTab.Title = 'Video Selection';
            app.SelectavideotoperformGazeEstimationonLabel = uilabel(app.VideoSelectionTab);
            app.SelectavideotoperformGazeEstimationonLabel.Text = 'Select a video to perform Gaze Estimation on:';
            app.SelectavideoforrelevantStereoscopicCalibrationsegmentationLabel = uilabel(app.VideoSelectionTab);
            app.SelectavideoforrelevantStereoscopicCalibrationsegmentationLabel.Text = {'Select a video for relevant Stereoscopic'; 'Calibration segmentation:'};
            app.EditField = uieditfield(app.VideoSelectionTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.VideoSelectionTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.BrowseButton = uibutton(app.VideoSelectionTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.VideoSelectionTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.CalibrationVideoSegmentationTab = uitab(app.TabGroup);
            app.CalibrationVideoSegmentationTab.Title = 'Calibration Video Segmentation';
            app.StartFrameDissectionButton = uibutton(app.CalibrationVideoSegmentationTab, 'push');
            app.StartFrameDissectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartFrameDissectionButtonPushed, true);
            app.StartFrameDissectionButton.Text = 'Start Frame Dissection';
            app.VideoLabel = uilabel(app.CalibrationVideoSegmentationTab);
            app.VideoLabel.Text = 'Video:';
            app.CVDFname = uilabel(app.CalibrationVideoSegmentationTab);
            app.CVDFname.Text = '';
            app.FrameSkipLabel = uilabel(app.CalibrationVideoSegmentationTab);
            app.FrameSkipLabel.Text = 'Frame Skip:';
            app.FrameSkipSpinner = uispinner(app.CalibrationVideoSegmentationTab);
            app.FrameSkipSpinner.Limits = [1 Inf];
            app.FrameSkipSpinner.RoundFractionalValues = 'on';
            app.FrameSkipSpinner.ValueDisplayFormat = '%.0f';
            app.FrameSkipSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSkipSpinnerValueChanged, true);
            app.FrameSkipSpinner.Value = 60;
            app.SaveinEditFieldLabel = uilabel(app.CalibrationVideoSegmentationTab);
            app.SaveinEditFieldLabel.Text = 'Save in:';
            app.SaveinEditField = uieditfield(app.CalibrationVideoSegmentationTab, 'text');
            app.SaveinEditField.ValueChangedFcn = createCallbackFcn(app, @SaveinEditFieldValueChanged, true);
            app.BrowseButton_3 = uibutton(app.CalibrationVideoSegmentationTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.FrameNumberLabel = uilabel(app.CalibrationVideoSegmentationTab);
            app.FrameNumberLabel.Text = 'Frame Number';
            app.FrameNumberGauge = uigauge(app.CalibrationVideoSegmentationTab, 'linear');
            app.FrameNumberGauge.Limits = [1 100];
            app.FrameNumberGauge.Value = 1;
            app.DissectionLamp = uilamp(app.CalibrationVideoSegmentationTab);
            app.FinLabel = uilabel(app.CalibrationVideoSegmentationTab);
            app.FinLabel.Visible = 'off';
            app.FinLabel.Text = 'Finished Dissection';
            app.Label = uilabel(app.CalibrationVideoSegmentationTab);
            app.Label.Text = {'You may use the Stereo Camera Calibrator app in the vision toolbox, this involves'; 'the process of taking multiple pictures of a checkerboard. Instead, you may use '; 'this segmentation tab to convert a video of the checkerboard to discrete frames.'; 'Video must be in 3840x1080 format, with the left and right cameras side-by-side.'; 'Frame skip allows skipping a number of frames before taking an image.'};
            app.Label_2 = uilabel(app.CalibrationVideoSegmentationTab);
            app.Label_2.Text = 'Stereoscopic camera parameters are optional to rectify stereo images accordingly.';
            app.HaarCascadeROITab = uitab(app.TabGroup);
            app.HaarCascadeROITab.Title = 'Haar Cascade ROI';
            app.UIAxes = uiaxes(app.HaarCascadeROITab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1 2];
            app.SelectaframewhereboundingboxesareclearlypresentforalleyesLabel = uilabel(app.HaarCascadeROITab);
            app.SelectaframewhereboundingboxesareclearlypresentforalleyesLabel.Text = 'Select a frame where bounding boxes are clearly present for all eyes:';
            app.Label_3 = uilabel(app.HaarCascadeROITab);
            app.Label_3.Text = {'Regions of interest are required to perform centre calibration as well as '; 'performing Iris extraction. First load the video, then choose a frame where '; 'both eyes are visible in the bounding boxes. '; 'Once that is done, click "Save Bounds"'};
            app.SaveBoundsButton = uibutton(app.HaarCascadeROITab, 'push');
            app.SaveBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBoundsButtonPushed, true);
            app.SaveBoundsButton.Text = 'Save Bounds';
            app.FrameNoSpinnerLabel = uilabel(app.HaarCascadeROITab);
            app.FrameNoSpinnerLabel.Text = 'Frame No.';
            app.FrameNoSpinner = uispinner(app.HaarCascadeROITab);
            app.FrameNoSpinner.Limits = [1 Inf];
            app.FrameNoSpinner.RoundFractionalValues = 'on';
            app.FrameNoSpinner.ValueDisplayFormat = '%.0f';
            app.FrameNoSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameNoSpinnerValueChanged, true);
            app.FrameNoSpinner.Value = 1;
            app.FrameNumberGauge_2 = uigauge(app.HaarCascadeROITab, 'linear');
            app.VideoLabel_2 = uilabel(app.HaarCascadeROITab);
            app.VideoLabel_2.Text = 'Video:';
            app.HaarV = uilabel(app.HaarCascadeROITab);
            app.HaarV.Text = '';
            app.LoadVideoButton = uibutton(app.HaarCascadeROITab, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.NextFrameButton = uibutton(app.HaarCascadeROITab, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Text = 'Next Frame';
            app.BoundSavedLabel = uilabel(app.HaarCascadeROITab);
            app.BoundSavedLabel.Visible = 'off';
            app.BoundSavedLabel.Text = 'Bounds have been saved';
            app.VideoLoadedintoMemoryLabel = uilabel(app.HaarCascadeROITab);
            app.VideoLoadedintoMemoryLabel.Visible = 'off';
            app.VideoLoadedintoMemoryLabel.Text = 'Video Loaded into Memory';
            app.ThinkingLabel = uilabel(app.HaarCascadeROITab);
            app.ThinkingLabel.Visible = 'off';
            app.ThinkingLabel.Text = 'Thinking...';
            app.CentreGazeCoordinatesTab = uitab(app.TabGroup);
            app.CentreGazeCoordinatesTab.Title = 'Centre Gaze Coordinates';
            app.FrameNumberGauge_3 = uigauge(app.CentreGazeCoordinatesTab, 'linear');
            app.CentreGazeCalibrationLabel = uilabel(app.CentreGazeCoordinatesTab);
            app.CentreGazeCalibrationLabel.Text = 'Centre Gaze Calibration';
            app.Label_4 = uilabel(app.CentreGazeCoordinatesTab);
            app.Label_4.Text = {'Select a frame where the user is looking at the centre (origin) of the intended plane.'; 'This will be used to establish the ''ground truth''. Load the video into memory first.'};
            app.LoadVideoButton_2 = uibutton(app.CentreGazeCoordinatesTab, 'push');
            app.LoadVideoButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButton_2Pushed, true);
            app.LoadVideoButton_2.Text = 'Load Video';
            app.HaarV_2 = uilabel(app.CentreGazeCoordinatesTab);
            app.HaarV_2.Text = '';
            app.FrameNoSpinner_2Label = uilabel(app.CentreGazeCoordinatesTab);
            app.FrameNoSpinner_2Label.Text = 'Frame No.';
            app.FrameNoSpinner_2 = uispinner(app.CentreGazeCoordinatesTab);
            app.FrameNoSpinner_2.Limits = [1 Inf];
            app.FrameNoSpinner_2.RoundFractionalValues = 'on';
            app.FrameNoSpinner_2.ValueDisplayFormat = '%.0f';
            app.FrameNoSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FrameNoSpinner_2ValueChanged, true);
            app.FrameNoSpinner_2.Value = 1;
            app.UIAxes2 = uiaxes(app.CentreGazeCoordinatesTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.CheckFrameButton = uibutton(app.CentreGazeCoordinatesTab, 'push');
            app.CheckFrameButton.ButtonPushedFcn = createCallbackFcn(app, @CheckFrameButtonPushed, true);
            app.CheckFrameButton.Text = 'Check Frame';
            app.VideoLoadedCheckaframethensaveitLabel = uilabel(app.CentreGazeCoordinatesTab);
            app.VideoLoadedCheckaframethensaveitLabel.Visible = 'off';
            app.VideoLoadedCheckaframethensaveitLabel.Text = {'Video Loaded. Select a frame number and check it.'; 'If it is the correct frame, save it.'};
            app.SaveCoordinatesButton = uibutton(app.CentreGazeCoordinatesTab, 'push');
            app.SaveCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCoordinatesButtonPushed, true);
            app.SaveCoordinatesButton.Text = 'Save Coordinates';
            app.CoordinatessavedLabel = uilabel(app.CentreGazeCoordinatesTab);
            app.CoordinatessavedLabel.Visible = 'off';
            app.CoordinatessavedLabel.Text = 'Coordinates saved';
            app.ProcessLabel = uilabel(app.CentreGazeCoordinatesTab);
            app.ProcessLabel.Visible = 'off';
            app.ProcessLabel.Text = {'Processing... '; 'Please be patient.'};
            app.GazeEstimationTab = uitab(app.TabGroup);
            app.GazeEstimationTab.Title = 'Gaze Estimation';
            app.ChecklistbeforeGazeEstimationLabel = uilabel(app.GazeEstimationTab);
            app.ChecklistbeforeGazeEstimationLabel.Text = 'Checklist before Gaze Estimation:';
            app.LampVid = uilamp(app.GazeEstimationTab);
            app.LampROI = uilamp(app.GazeEstimationTab);
            app.LampCentre = uilamp(app.GazeEstimationTab);
            app.VideoSelectedLabel = uilabel(app.GazeEstimationTab);
            app.VideoSelectedLabel.Text = 'Video Selected:';
            app.HaarROIBoundsFoundLabel = uilabel(app.GazeEstimationTab);
            app.HaarROIBoundsFoundLabel.Text = 'Haar ROI Bounds Found:';
            app.CentreGazeCoordinatesFoundLabel = uilabel(app.GazeEstimationTab);
            app.CentreGazeCoordinatesFoundLabel.Text = 'Centre Gaze Coordinates Found:';
            app.Gauge = uigauge(app.GazeEstimationTab, 'linear');
            app.LoadVideoButton_3 = uibutton(app.GazeEstimationTab, 'push');
            app.LoadVideoButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButton_3Pushed, true);
            app.LoadVideoButton_3.Text = 'Load Video';
            app.Label_5 = uilabel(app.GazeEstimationTab);
            app.Label_5.Text = {'Ensure that the checklist is complete before performing gaze estimation.'; 'Load the video using the ''Load Video'' button and ensure that the rest of '; 'the checklist is complete. If not, go back to the relevant tab. '};
            app.StartGazeEstimationButton = uibutton(app.GazeEstimationTab, 'push');
            app.StartGazeEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartGazeEstimationButtonPushed, true);
            app.StartGazeEstimationButton.Text = 'Start Gaze Estimation';
            app.VideoSaveLocationLabel = uilabel(app.GazeEstimationTab);
            app.VideoSaveLocationLabel.Text = 'Video Save Location:';
            app.EditField_3 = uieditfield(app.GazeEstimationTab, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.BrowseButton_4 = uibutton(app.GazeEstimationTab, 'push');
            app.BrowseButton_4.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_4Pushed, true);
            app.BrowseButton_4.Text = 'Browse';
            app.Processing = uilamp(app.GazeEstimationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stereoscopic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end